classdef P1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel3                       matlab.ui.container.Panel
        GenerarsealPanel             matlab.ui.container.Panel
        GenerarButton                matlab.ui.control.Button
        DutycycleEditField           matlab.ui.control.NumericEditField
        Dutycycle01EditField_2Label  matlab.ui.control.Label
        PeriodosEditField            matlab.ui.control.NumericEditField
        PeriodosEditFieldLabel       matlab.ui.control.Label
        FsEditField                  matlab.ui.control.NumericEditField
        FsHzEditFieldLabel           matlab.ui.control.Label
        DuracionEditField            matlab.ui.control.NumericEditField
        DuracionEditFieldLabel       matlab.ui.control.Label
        FEditField                   matlab.ui.control.NumericEditField
        FHzEditFieldLabel            matlab.ui.control.Label
        AmplitudEditField            matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel       matlab.ui.control.Label
        TipoDropDown                 matlab.ui.control.DropDown
        TipoLabel                    matlab.ui.control.Label
        ImportararchivodeaudioPanel  matlab.ui.container.Panel
        GuardarButton_2              matlab.ui.control.Button
        DropDown_2                   matlab.ui.control.DropDown
        EditField                    matlab.ui.control.EditField
        AbrirButton                  matlab.ui.control.Button
        Panel2                       matlab.ui.container.Panel
        UIAxes_Frequency_2           matlab.ui.control.UIAxes
        UIAxes_Time_2                matlab.ui.control.UIAxes
        UIAxes_Frequency             matlab.ui.control.UIAxes
        UIAxes_Time                  matlab.ui.control.UIAxes
        EfectosPanel                 matlab.ui.container.Panel
        FiltrosPanel                 matlab.ui.container.Panel
        SumadesealesPanel            matlab.ui.container.Panel
        Seal2Button                  matlab.ui.control.Button
        Seal1Button                  matlab.ui.control.Button
        AcumularButton               matlab.ui.control.Button
        OperacinDropDown             matlab.ui.control.DropDown
        OperacinDropDownLabel        matlab.ui.control.Label
        AudioPanel                   matlab.ui.container.Panel
        GrabacinPanel                matlab.ui.container.Panel
        RecordingLabel               matlab.ui.control.Label
        RecordButton                 matlab.ui.control.StateButton
        StopRecordingButton          matlab.ui.control.Button
        ControlesPanel               matlab.ui.container.Panel
        DropDown                     matlab.ui.control.DropDown
        StopButton                   matlab.ui.control.Button
        PauseButton                  matlab.ui.control.Button
        PlayButton                   matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
        Property2 % Description
        recObj    % Objeto de grabación
        y  % Datos del archivo de audio
        cursorLine % Línea que actúa como cursor en la gráfica
        isPlaying % Variable para saber si el audio está reproduciéndose
        recorder % Objeto audiorecorder para grabar audio
        recordedAudio % Datos del audio grabado
        recordedFs % Frecuencia de muestreo del audio grabado
        isRecording % Flag para indicar que se está grabando
        audioDataSlot1 % Datos de audio para la señal 1
        audioDataSlot2 % Datos de audio para la señal 2
        Fs1        % Frecuencia de muestreo para la señal 1
        Fs2        % Frecuencia de muestreo para la señal 2
        playerSlot1    % Objeto audioplayer para la señal 1
        playerSlot2    % Objeto audioplayer para la señal 2
        accumulatedSignal % Señal acumulada
        FsAccumulated % Frecuencia de muestreo de la señal acumulada
    end

    methods (Access = private)
        % Función para actualizar el cursor en la gráfica
        function updateCursor(app)
            if app.isPlaying
                currentTime = app.player.CurrentSample / app.Fs1; % Tiempo actual de reproducción
                set(app.cursorLine, 'XData', [currentTime currentTime]); % Actualiza la posición del cursor
            end
        end

        function updatePlot(app, slot)
            if strcmp(slot, 'Señal 1') && ~isempty(app.audioDataSlot1)
                % Graficar señal en el tiempo
                plot(app.UIAxes_Time, (1:length(app.audioDataSlot1)) / app.Fs1, app.audioDataSlot1);
                title(app.UIAxes_Time, 'Señal 1 - Eje temporal');
                xlabel(app.UIAxes_Time, 'Tiempo (s)');
                ylabel(app.UIAxes_Time, 'Amplitud');

                % Graficar espectro de frecuencia
                L = length(app.audioDataSlot1);
                Y = fft(app.audioDataSlot1);
                P2 = abs(Y / L);
                P1 = P2(1:floor(L / 2) + 1);
                P1(2:end - 1) = 2 * P1(2:end - 1);
                f = app.Fs1 * (0:(L / 2)) / L;
                plot(app.UIAxes_Frequency, f, P1);
                title(app.UIAxes_Frequency, 'Señal 1 - Espectro de Frecuencia');
                xlabel(app.UIAxes_Frequency, 'Frecuencia (Hz)');
                ylabel(app.UIAxes_Frequency, 'Amplitud');
            elseif strcmp(slot, 'Señal 2') && ~isempty(app.audioDataSlot2)
                % Graficar señal en el tiempo
                plot(app.UIAxes_Time_2, (1:length(app.audioDataSlot2)) / app.Fs2, app.audioDataSlot2);
                title(app.UIAxes_Time_2, 'Señal 2 - Eje temporal');
                xlabel(app.UIAxes_Time_2, 'Tiempo (s)');
                ylabel(app.UIAxes_Time_2, 'Amplitud');

                % Graficar espectro de frecuencia
                L = length(app.audioDataSlot2);
                Y = fft(app.audioDataSlot2);
                P2 = abs(Y / L);
                P1 = P2(1:floor(L / 2) + 1);
                P1(2:end - 1) = 2 * P1(2:end - 1);
                f = app.Fs2 * (0:(L / 2)) / L;
                plot(app.UIAxes_Frequency_2, f, P1);
                title(app.UIAxes_Frequency_2, 'Señal 2 - Espectro de Frecuencia');
                xlabel(app.UIAxes_Frequency_2, 'Frecuencia (Hz)');
                ylabel(app.UIAxes_Frequency_2, 'Amplitud');
            end
        end

        function plotFrequencySpectrum(app)
            if ~isempty(app.y) && ~isempty(app.Fs1)
                % Get the length of the signal
                L = length(app.y);

                % If stereo, convert to mono
                if size(app.y, 2) == 2
                    y_mono = mean(app.y, 2);
                else
                    y_mono = app.y;
                end

                % Compute the Fourier transform of the signal
                Y = fft(y_mono);

                % Compute the two-sided spectrum P2
                P2 = abs(Y/L);

                % Compute the single-sided spectrum P1 based on P2 and the even-valued signal length L
                P1 = P2(1:floor(L/2)+1);
                P1(2:end-1) = 2*P1(2:end-1);

                % Define the frequency domain f
                f = app.Fs1 * (0:(L/2))/L;

                % Plot the single-sided amplitude spectrum
                plot(app.UIAxes_Frequency, f, P1);

                % Set labels and title
                title(app.UIAxes_Frequency, 'Señal 1 - Single-Sided Amplitude Spectrum');
                xlabel(app.UIAxes_Frequency, 'Frequency (Hz)');
                ylabel(app.UIAxes_Frequency, 'Amplitud');

                % Set x-axis to logarithmic scale for better visualization
                app.UIAxes_Frequency.XScale = 'log';

                % Adjust x-axis limits to show from 20 Hz to Nyquist frequency
                xlim(app.UIAxes_Frequency, [20 app.Fs1/2]);
            else
                % If no audio loaded, display a message
                cla(app.UIAxes_Frequency);
                text(app.UIAxes_Frequency, 0.5, 0.5, 'No audio loaded', 'HorizontalAlignment', 'center');
            end
        end

        % Función para cargar un archivo de audio
        function loadAudio(app, slot)
            [file, path] = uigetfile('*.wav', 'Select an audio file');
            if isequal(file, 0)
                return;
            end
            [y, Fs1] = audioread(fullfile(path, file));
            if strcmp(slot, 'Señal 1')
                app.audioDataSlot1 = y;
                app.Fs1 = Fs1;
                updatePlot(app, 'Señal 1');
            elseif strcmp(slot, 'Señal 2')
                app.audioDataSlot2 = y;
                app.Fs2 = Fs1;
                updatePlot(app, 'Señal 2');
            end
        end

        % Función para acumular señales
        function accumulateSignals(app)
            if isempty(app.audioDataSlot1)
                app.accumulatedSignal = app.audioDataSlot2;
                app.FsAccumulated = app.Fs2;
            elseif isempty(app.audioDataSlot2)
                app.accumulatedSignal = app.audioDataSlot1;
                app.FsAccumulated = app.Fs1;
            else
                % Asegurarse de que las señales tengan la misma frecuencia de muestreo
                if app.Fs1 ~= app.Fs2
                    uialert(app.UIFigure, 'Las frecuencias de muestreo de las señales no coinciden.', 'Error');
                    return;
                end
                % Asegurar que ambas señales tengan el mismo tamaño
                minLength = min(length(app.audioDataSlot1), length(app.audioDataSlot2));
                signal1 = app.audioDataSlot1(1:minLength);
                signal2 = app.audioDataSlot2(1:minLength);
                % Sumar las señales
                app.accumulatedSignal = signal1 + signal2;
                app.FsAccumulated = app.Fs1;
            end
            % Actualizar la señal acumulada en la gráfica de Señal 1
            app.audioDataSlot1 = app.accumulatedSignal;
            app.Fs1 = app.FsAccumulated;
            updatePlot(app, 'Señal 1');
        end

        function generateSignal(app)
            tipo = app.TipoDropDown.Value;
            amp = app.AmplitudEditField.Value;
            freq = app.FEditField.Value;
            Fs = app.FsEditField.Value;
            duracion = app.DuracionEditField.Value;
            dutyCycle = app.DutycycleEditField.Value;
            t = 0:1/Fs:duracion;
            
            switch tipo
                case 'Seno'
                    y = amp * sin(2 * pi * freq * t);
                case 'Cuadrada'
                    y = amp * square(2 * pi * freq * t, dutyCycle * 100);
                case 'Triangular'
                    y = amp * sawtooth(2 * pi * freq * t, 0.5);
                case 'Dientes de sierra'
                    y = amp * sawtooth(2 * pi * freq * t);
                otherwise
                    y = zeros(size(t));
            end
            
            app.audioDataSlot1 = y;
            app.Fs1 = Fs;
            updatePlot(app, 'Señal 1');
        end

        % Función para reproducir audio
        function playAudio(app, slot)
            if strcmp(slot, 'Señal 1') && ~isempty(app.audioDataSlot1)
                if isempty(app.playerSlot1) || ~isplaying(app.playerSlot1)
                    app.playerSlot1 = audioplayer(app.audioDataSlot1, app.Fs1);
                    play(app.playerSlot1);
                end
            elseif strcmp(slot, 'Señal 2') && ~isempty(app.audioDataSlot2)
                if isempty(app.playerSlot2) || ~isplaying(app.playerSlot2)
                    app.playerSlot2 = audioplayer(app.audioDataSlot2, app.Fs2);
                    play(app.playerSlot2);
                end
            end
        end

        % Función para pausar la reproducción
        function pauseAudio(app, slot)
            if strcmp(slot, 'Señal 1') && ~isempty(app.playerSlot1) && isplaying(app.playerSlot1)
                pause(app.playerSlot1);
            elseif strcmp(slot, 'Señal 2') && ~isempty(app.playerSlot2) && isplaying(app.playerSlot2)
                pause(app.playerSlot2);
            end
        end

        % Función para detener la reproducción
        function stopAudio(app, slot)
            if strcmp(slot, 'Señal 1') && ~isempty(app.playerSlot1)
                stop(app.playerSlot1);
            elseif strcmp(slot, 'Señal 2') && ~isempty(app.playerSlot2)
                stop(app.playerSlot2);
            end
        end

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AbrirButton
        function LoadAudioButtonPushed(app, event)
            slot = app.DropDown_2.Value;
            loadAudio(app, slot);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditFieldValueChanged.Value;
        end

        % Button pushed function: PlayButton
        function PlayPlayButtonPushed(app, event)
            slot = app.DropDown.Value;
            playAudio(app, slot);
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            slot = app.DropDown.Value;
            pauseAudio(app, slot);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            slot = app.DropDown.Value;
            stopAudio(app, slot);
        end

        % Callback function: not associated with a component
        function StartRecordingButtonPushed(app, event)
            app.recordedFs = 44100; % Standard sampling frequency
            numChannels = 1; % Mono

            app.recorder = audiorecorder(app.recordedFs, 16, numChannels);

            record(app.recorder);
            app.isRecording = true;
            app.RecordingLabel.Text = 'Recording...';
            app.RecordingLabel.FontColor = [1 0 0]; % Red color
        end

        % Button pushed function: StopRecordingButton
        function StopRecordingButtonPushed(app, event)
            if ~isempty(app.recorder) && app.isRecording
                stop(app.recorder);
                app.isRecording = false;
                app.RecordingLabel.Text = 'Recording Stopped';
                app.RecordingLabel.FontColor = [0 0 0]; % Black color

                app.y = getaudiodata(app.recorder);
                app.Fs1 = app.recordedFs;

                updatePlot(app);

                % Create audioplayer object for the recorded audio
                app.player = audioplayer(app.y, app.Fs1);
            end
        end

        % Callback function: not associated with a component
        function SaveRecordingButtonPushed(app, event)
            if isempty(app.y)
                uialert(app.UIFigure, 'No audio data to save.', 'Error');
            else
                [file, path] = uiputfile('*.wav', 'Save audio file');
                if ~isequal(file, 0)
                    fullFileName = fullfile(path, file);
                    audiowrite(fullFileName, app.y, app.Fs1);
                    app.EditField.Value = fullFileName;
                    uialert(app.UIFigure, ['File saved: ' fullFileName], 'Success');
                end
            end
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            generateSignal(app);
        end

        % Button pushed function: AcumularButton
        function AcumularButtonPushed(app, event)
            accumulateSignals(app);
        end

        % Button pushed function: GuardarButton_2
        function GuardarButtonPushed(app, event)
            if isempty(app.audioDataSlot1)
                uialert(app.UIFigure, 'No hay datos de audio en el slot 1 para guardar.', 'Error');
            else
                [file, path] = uiputfile('*.wav', 'Save audio file');
                if ~isequal(file, 0)
                    fullFileName = fullfile(path, file);
                    audiowrite(fullFileName, app.audioDataSlot1, app.Fs1);
                    app.EditField.Value = fullFileName;
                    uialert(app.UIFigure, ['Archivo guardado: ' fullFileName], 'Success');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AudioPanel = uipanel(app.UIFigure);
            app.AudioPanel.Title = 'Audio';
            app.ControlesPanel = uipanel(app.AudioPanel);
            app.ControlesPanel.Title = 'Controles';
            app.PlayButton = uibutton(app.ControlesPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPlayButtonPushed, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'imgs', 'play.svg');
            app.PlayButton.Text = '';
            app.PauseButton = uibutton(app.ControlesPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = fullfile(pathToMLAPP, 'imgs', 'pause.svg');
            app.PauseButton.Text = '';
            app.StopButton = uibutton(app.ControlesPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, 'imgs', 'square.svg');
            app.StopButton.Text = '';
            app.DropDown = uidropdown(app.ControlesPanel);
            app.DropDown.Items = {'Señal 1', 'Señal 2'};
            app.DropDown.Value = 'Señal 1';
            app.GrabacinPanel = uipanel(app.AudioPanel);
            app.GrabacinPanel.Title = 'Grabación';
            app.StopRecordingButton = uibutton(app.GrabacinPanel, 'push');
            app.StopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordingButtonPushed, true);
            app.StopRecordingButton.Icon = fullfile(pathToMLAPP, 'imgs', 'square.svg');
            app.StopRecordingButton.Text = '';
            app.RecordButton = uibutton(app.GrabacinPanel, 'state');
            app.RecordButton.Icon = 'C:\Users\mario\OneDrive - UVa\Universidad\4\FSI\Bloque_1\imgs\mic.svg';
            app.RecordButton.Text = '';
            app.RecordingLabel = uilabel(app.GrabacinPanel);
            app.RecordingLabel.Text = '[...]';
            app.EfectosPanel = uipanel(app.UIFigure);
            app.EfectosPanel.Title = 'Efectos';
            app.SumadesealesPanel = uipanel(app.EfectosPanel);
            app.SumadesealesPanel.Title = 'Suma de señales';
            app.OperacinDropDownLabel = uilabel(app.SumadesealesPanel);
            app.OperacinDropDownLabel.Text = 'Operación';
            app.OperacinDropDown = uidropdown(app.SumadesealesPanel);
            app.OperacinDropDown.Items = {'Suma', 'Resta', 'Producto', 'Cociente'};
            app.OperacinDropDown.Value = 'Suma';
            app.AcumularButton = uibutton(app.SumadesealesPanel, 'push');
            app.AcumularButton.ButtonPushedFcn = createCallbackFcn(app, @AcumularButtonPushed, true);
            app.AcumularButton.Text = 'Acumular';
            app.Seal1Button = uibutton(app.SumadesealesPanel, 'push');
            app.Seal1Button.Text = 'Señal 1';
            app.Seal2Button = uibutton(app.SumadesealesPanel, 'push');
            app.Seal2Button.Text = 'Señal 2';
            app.FiltrosPanel = uipanel(app.EfectosPanel);
            app.FiltrosPanel.Title = 'Filtros';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.UIAxes_Time = uiaxes(app.Panel2);
            title(app.UIAxes_Time, 'Title')
            xlabel(app.UIAxes_Time, 'X')
            ylabel(app.UIAxes_Time, 'Y')
            zlabel(app.UIAxes_Time, 'Z')
            app.UIAxes_Frequency = uiaxes(app.Panel2);
            title(app.UIAxes_Frequency, 'Title')
            xlabel(app.UIAxes_Frequency, 'X')
            ylabel(app.UIAxes_Frequency, 'Y')
            zlabel(app.UIAxes_Frequency, 'Z')
            app.UIAxes_Time_2 = uiaxes(app.Panel2);
            title(app.UIAxes_Time_2, 'Title')
            xlabel(app.UIAxes_Time_2, 'X')
            ylabel(app.UIAxes_Time_2, 'Y')
            zlabel(app.UIAxes_Time_2, 'Z')
            app.UIAxes_Frequency_2 = uiaxes(app.Panel2);
            title(app.UIAxes_Frequency_2, 'Title')
            xlabel(app.UIAxes_Frequency_2, 'X')
            ylabel(app.UIAxes_Frequency_2, 'Y')
            zlabel(app.UIAxes_Frequency_2, 'Z')
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Panel3';
            app.ImportararchivodeaudioPanel = uipanel(app.Panel3);
            app.ImportararchivodeaudioPanel.Title = 'Importar archivo de audio';
            app.AbrirButton = uibutton(app.ImportararchivodeaudioPanel, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioButtonPushed, true);
            app.AbrirButton.Text = 'Abrir';
            app.EditField = uieditfield(app.ImportararchivodeaudioPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tag = 'EditFieldValueChanged';
            app.DropDown_2 = uidropdown(app.ImportararchivodeaudioPanel);
            app.DropDown_2.Items = {'Señal 1', 'Señal 2'};
            app.DropDown_2.Value = 'Señal 1';
            app.GuardarButton_2 = uibutton(app.ImportararchivodeaudioPanel, 'push');
            app.GuardarButton_2.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton_2.Text = 'Guardar';
            app.GenerarsealPanel = uipanel(app.Panel3);
            app.GenerarsealPanel.Title = 'Generar señal';
            app.TipoLabel = uilabel(app.GenerarsealPanel);
            app.TipoLabel.Text = 'Tipo';
            app.TipoDropDown = uidropdown(app.GenerarsealPanel);
            app.TipoDropDown.Items = {'Seno', 'Coseno', 'Cuadrada', 'Rectangular', 'Triangular', 'Dientes de sierra', 'Chirp'};
            app.TipoDropDown.Value = 'Seno';
            app.AmplitudEditFieldLabel = uilabel(app.GenerarsealPanel);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.GenerarsealPanel, 'numeric');
            app.FHzEditFieldLabel = uilabel(app.GenerarsealPanel);
            app.FHzEditFieldLabel.Text = 'F (Hz)';
            app.FEditField = uieditfield(app.GenerarsealPanel, 'numeric');
            app.DuracionEditFieldLabel = uilabel(app.GenerarsealPanel);
            app.DuracionEditFieldLabel.Text = 'Duracion';
            app.DuracionEditField = uieditfield(app.GenerarsealPanel, 'numeric');
            app.FsHzEditFieldLabel = uilabel(app.GenerarsealPanel);
            app.FsHzEditFieldLabel.Text = 'Fs (Hz)';
            app.FsEditField = uieditfield(app.GenerarsealPanel, 'numeric');
            app.PeriodosEditFieldLabel = uilabel(app.GenerarsealPanel);
            app.PeriodosEditFieldLabel.Text = 'Periodos';
            app.PeriodosEditField = uieditfield(app.GenerarsealPanel, 'numeric');
            app.Dutycycle01EditField_2Label = uilabel(app.GenerarsealPanel);
            app.Dutycycle01EditField_2Label.Text = 'Duty  cycle [0-1]';
            app.DutycycleEditField = uieditfield(app.GenerarsealPanel, 'numeric');
            app.GenerarButton = uibutton(app.GenerarsealPanel, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end